classdef HVAC_app_Minerva < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAUA_CLTD69theditionUIFigure    matlab.ui.Figure
        MAUA_CLTDstandardeditionLabel   matlab.ui.control.Label
        SelectCityDropDownLabel         matlab.ui.control.Label
        SelectCityDropDown              matlab.ui.control.DropDown
        SelectdesigntemperatureCEditFieldLabel  matlab.ui.control.Label
        SelectdesigntemperatureCEditField  matlab.ui.control.NumericEditField
        LatitudeLabel                   matlab.ui.control.Label
        MaxHumidityLabel                matlab.ui.control.Label
        MonthLabel                      matlab.ui.control.Label
        MaxTemperatureLabel             matlab.ui.control.Label
        ElectricalLoadButton            matlab.ui.control.Button
        HumanLoadButton                 matlab.ui.control.Button
        VentilationButton               matlab.ui.control.Button
        InfiltrationLoadButton          matlab.ui.control.Button
        WallloadButton                  matlab.ui.control.Button
        WindowLoadButton                matlab.ui.control.Button
        EquipmentloadPanel              matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        CalculateButton                 matlab.ui.control.Button
        ElectricalLoadLabel             matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        SelectZoneDropDownLabel         matlab.ui.control.Label
        SelectZoneDropDown              matlab.ui.control.DropDown
        ExitButton                      matlab.ui.control.Button
        HumanLoadPanel                  matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        CalculateButton_2               matlab.ui.control.Button
        HumanLabel                      matlab.ui.control.Label
        ExitButton_2                    matlab.ui.control.Button
        VentilationRatePanel            matlab.ui.container.Panel
        EnterNumberofPeopleLabel        matlab.ui.control.Label
        peopleEditField                 matlab.ui.control.NumericEditField
        SelectTypeofroomsDropDownLabel  matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        Smoking                         matlab.ui.control.CheckBox
        CalculateButton_3               matlab.ui.control.Button
        ExitButton_3                    matlab.ui.control.Button
        VentflowrateLabel               matlab.ui.control.Label
        InfiltrationloadPanel           matlab.ui.container.Panel
        SelectconstructionDropDownLabel  matlab.ui.control.Label
        SelectconstructionDropDown      matlab.ui.control.DropDown
        EntervolumeEditFieldLabel       matlab.ui.control.Label
        EntervolumeEditField            matlab.ui.control.NumericEditField
        CalculateButton_4               matlab.ui.control.Button
        LoadhereLabel                   matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        WallloadPanel                   matlab.ui.container.Panel
        WalltypeEditFieldLabel          matlab.ui.control.Label
        WalltypeEditField               matlab.ui.control.NumericEditField
        WallareaEditFieldLabel          matlab.ui.control.Label
        WallareaEditField               matlab.ui.control.NumericEditField
        WallresistanceEditFieldLabel    matlab.ui.control.Label
        WallresistanceEditField         matlab.ui.control.NumericEditField
        WallfaceDropDownLabel           matlab.ui.control.Label
        WallfaceDropDown                matlab.ui.control.DropDown
        CalculateButton_5               matlab.ui.control.Button
        LoadhereLabel_2                 matlab.ui.control.Label
        Add                             matlab.ui.control.Button
        closeButton                     matlab.ui.control.Button
        TotalLoadLabel                  matlab.ui.control.Label
        SolarLoadPanel                  matlab.ui.container.Panel
        WindowexposedareaEditFieldLabel  matlab.ui.control.Label
        WindowexposedareaEditField      matlab.ui.control.NumericEditField
        WindowresistanceEditFieldLabel  matlab.ui.control.Label
        WindowresistanceEditField       matlab.ui.control.NumericEditField
        WindowfaceDropDownLabel         matlab.ui.control.Label
        WindowfaceDropDown              matlab.ui.control.DropDown
        CalculateButton_6               matlab.ui.control.Button
        LoadhereLabel_3                 matlab.ui.control.Label
        Add_2                           matlab.ui.control.Button
        closeButton_2                   matlab.ui.control.Button
        TotalLoadLabel_2                matlab.ui.control.Label
        ZonetypeDropDownLabel           matlab.ui.control.Label
        ZonetypeDropDown                matlab.ui.control.DropDown
    end


    properties (Access = public)
        %%Design parameters
        insideTemp=24
        relinsHumidity=0.5
        ratinsHumidity=0.00938
        outsideTemp  % Description
        relHumidity 
        ratHumidity 
        Latitude 
        %%Individual results
        equipmentLoad =0
        humanLoad =0
        ventRate =0
        infiltration =0
        infiload =0
        conductionLoad=0
        SolarLoad =0
        %%Equipment factors
        LightCLF
        EquipmentCLF
        
    end

    methods (Access = public)
    
        function UpdateLoad(app)
            app.TotalLoadLabel.Text=num2str(app.equipmentLoad+app.humanLoad+app.infiload+app.conductionLoad+app.SolarLoad);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EquipmentloadPanel.Visible='off';
            app.HumanLoadPanel.Visible='off';
            app.VentilationRatePanel.Visible='off';
            app.InfiltrationloadPanel.Visible='off';
            app.WallloadPanel.Visible='off';
            app.SolarLoadPanel.Visible='off';
            app.UITable.Data=zeros(8,5);
            app.UITable2.Data=zeros(8,2);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            CLTD=wallCLTD(app.Latitude, app.WallfaceDropDown.Value);
            load=(CLTD+(25-app.insideTemp)+(app.outsideTemp-29)).*app.WallareaEditField.Value./app.WallresistanceEditField.Value;
            app.conductionLoad=app.conductionLoad+load/3430;
            app.TotalLoadLabel.Text=num2str(app.conductionLoad);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Load=app.UITable.Data(:,1).*app.UITable.Data(:,2).*app.UITable.Data(:,3);
            %Script format: [clf]=LightsCLF(time, totaltime)
            clf=LightsCLF(app.UITable.Data(1,4), 8);
            Load(1)=Load(1).*clf;
            clf=LightsCLF(app.UITable.Data(2,4), 8);
            Load(2)=Load(2).*clf;
            %script format: [clf]=HumanCLF(time, totaltime)
            for i=3:8
                clf=HumanCLF(app.UITable.Data(i,4), 8);
                Load(i)=Load(i).*clf;
            end
            %%
            app.ElectricalLoadLabel.Text=(num2str(sum(Load)/3430));
            app.equipmentLoad=sum(Load);
            UpdateLoad(app);
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            Load=zeros(8,1);
            
            for i=3:8
                clf=HumanCLF(app.UITable2.Data(i,2), 8);
                Load(i)=human(i, app.UITable2.Data(i,1), clf);
            end
            app.HumanLabel.Text=num2str(sum(Load)/3430);
            app.humanLoad=sum(Load)/3430;
            UpdateLoad(app);
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            if strcmp(app.TypeDropDown.Value, 'Office')
                type=0;
            elseif strcmp(app.TypeDropDown.Value, 'Meeting')
                type=1;
            elseif strcmp(app.TypeDropDown.Value, 'Lobby')
                type=2;
            end
            app.ventRate=ventilation(app.peopleEditField.Value, type, app.Smoking.Value, 0.8);
            app.VentflowrateLabel.Text=num2str(app.ventRate);
            UpdateLoad(app);
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            app.LoadhereLabel.Text=num2str(infiltration(0, 3, app.EntervolumeEditField.Value, app.outsideTemp-app.insideTemp, app.ratHumidity-app.ratinsHumidity, app.ventRate)/3430);
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            CLTD=wallCLTD(app.Latitude, app.WallfaceDropDown.Value);
            load=(CLTD+(25-app.insideTemp)+(app.outsideTemp-29)).*app.WallareaEditField.Value./app.WallresistanceEditField.Value;
            app.LoadhereLabel_2.Text=num2str(load);
        end

        % Value changed function: SelectCityDropDown
        function CitySelector(app, event)
            
            if strcmp(app.SelectCityDropDown.Value, 'Lahore')
                app.LatitudeLabel.Text='Latitude: 31.52 N';
                app.MaxTemperatureLabel.Text='Temperature: 40.4 C';
                app.MaxHumidityLabel.Text='Humidity: 22%';
                app.Latitude=32;
                app.outsideTemp=40.4;
                app.relHumidity=0.22;
                app.ratHumidity=0.01147;
                app.MonthLabel.Text='Month: July';
            elseif strcmp(app.SelectCityDropDown.Value, 'Karachi')
                app.LatitudeLabel.Text='Latitude: 24.86 N';
                app.MaxTemperatureLabel.Text='Temperature: 35.2 C';
                app.MaxHumidityLabel.Text='Humidity: 83%';
                app.Latitude=24;
                app.outsideTemp=35.2;
                app.relHumidity=0.83;
                app.ratHumidity=0.0313;
                app.MonthLabel.Text='Month: July';
            elseif strcmp(app.SelectCityDropDown.Value, 'Jacobabad')
                app.LatitudeLabel.Text='Latitude: 28.24 N';
                app.MaxTemperatureLabel.Text='Temperature: 45.9 C';
                app.MaxHumidityLabel.Text='Humidity: 31%';
                app.Latitude=28;
                app.outsideTemp=45.9;
                app.relHumidity=0.31;
                app.ratHumidity=0.02278;
                app.MonthLabel.Text='Month: July';
            elseif strcmp(app.SelectCityDropDown.Value, 'Islamabad')
                app.LatitudeLabel.Text='Latitude: 33.68 N';
                app.MaxTemperatureLabel.Text='Temperature: 39.1 C';
                app.MaxHumidityLabel.Text='Humidity: 26%';
                app.MonthLabel.Text='Month: July';
                app.Latitude=32;
                app.outsideTemp=39.1;
                app.relHumidity=0.26;
                app.ratHumidity=0.0125;
            elseif strcmp(app.SelectCityDropDown.Value, 'Gwadar')
                app.LatitudeLabel.Text='Latitude: 24.86 N';
                app.MaxTemperatureLabel.Text='Temperature: 35.2 C';
                app.MaxHumidityLabel.Text='Humidity: 83%';
                app.MonthLabel.Text='Month: July';
                app.Latitude=24;
                app.outsideTemp=35.2;
                app.relHumidity=0.83;
                app.ratHumidity=0.0313;
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.InfiltrationloadPanel.Visible='off';
        end

        % Button pushed function: ElectricalLoadButton
        function Equipment(app, event)
            app.EquipmentloadPanel.Visible='on';
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.EquipmentloadPanel.Visible='off';
            
        end

        % Button pushed function: ExitButton_2
        function ExitButton_2Pushed(app, event)
            app.HumanLoadPanel.Visible='off';
        end

        % Button pushed function: ExitButton_3
        function ExitButton_3Pushed(app, event)
            app.VentilationRatePanel.Visible='off';
            
        end

        % Button pushed function: HumanLoadButton
        function HumanLoadButtonPushed(app, event)
            app.HumanLoadPanel.Visible='on';
        end

        % Button pushed function: InfiltrationLoadButton
        function InfiltrationLoadButtonPushed(app, event)
            app.InfiltrationloadPanel.Visible='on';
        end

        % Callback function
        function SelectTypeofroomsDropDownValueChanged(app, event)
            
        end

        % Value changed function: SelectZoneDropDown
        function SelectZoneDropDownValueChanged(app, event)
            value = app.SelectZoneDropDown.Value;
            if strcmp(value, 'A')
                
            elseif strcmp(value, 'B')
                
            elseif strcmp(value, 'C')
                
            elseif strcmp(value, 'D')
                
            end
        end

        % Value changed function: SelectdesigntemperatureCEditField
        function SelectdesigntemperatureCEditFieldValueChanged(app, event)
            value = app.SelectdesigntemperatureCEditField.Value;
            app.insideTemp=value;
        end

        % Callback function
        function ShowtotalloadButtonPushed(app, event)
            UpdateLoad(app);
        end

        % Button pushed function: VentilationButton
        function VentilationButtonPushed(app, event)
            app.VentilationRatePanel.Visible='on';
        end

        % Button pushed function: WallloadButton
        function WallloadButtonPushed(app, event)
            app.WallloadPanel.Visible='on';
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            app.WallloadPanel.Visible='off';
        end

        % Button pushed function: CalculateButton_6
        function CalculateButton_6Pushed(app, event)
            CLTD=wallCLTD(app.Latitude, app.WindowfaceDropDown.Value);
            load=(CLTD+(25-app.insideTemp)+(app.outsideTemp-29)).*app.WindowexposedareaEditField.Value./app.WindowresistanceEditField.Value;
            app.LoadhereLabel_3.Text=num2str(load);
        end

        % Button pushed function: Add_2
        function Add_2ButtonPushed(app, event)
             CLTD=wallCLTD(app.Latitude, app.WindowfaceDropDown.Value);
            load=(CLTD+(25-app.insideTemp)+(app.outsideTemp-29)).*app.WindowexposedareaEditField.Value./app.WindowresistanceEditField.Value;
            app.LoadhereLabel_3.Text=num2str(load);
            app.SolarLoad=app.SolarLoad+load/3430;
            app.TotalLoadLabel_2.Text=num2str(app.SolarLoad);
        end

        % Button pushed function: WindowLoadButton
        function WindowLoadButtonPushed(app, event)
            app.SolarLoadPanel.Visible='on';
        end

        % Button pushed function: closeButton_2
        function closeButton_2Pushed(app, event)
            app.SolarLoadPanel.Visible='off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAUA_CLTD69theditionUIFigure = uifigure;
            app.MAUA_CLTD69theditionUIFigure.Name = 'MAUA_CLTD ~69th edition~';
            app.MAUA_CLTDstandardeditionLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.MAUA_CLTDstandardeditionLabel.Text = 'MAUA_CLTD ~standard edition~';
            app.SelectCityDropDownLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.SelectCityDropDownLabel.Text = 'Select City';
            app.SelectCityDropDown = uidropdown(app.MAUA_CLTD69theditionUIFigure);
            app.SelectCityDropDown.Items = {'Select City', 'Lahore', 'Karachi', 'Jacobabad', 'Islamabad', 'Gwadar'};
            app.SelectCityDropDown.ValueChangedFcn = createCallbackFcn(app, @CitySelector, true);
            app.SelectCityDropDown.Value = 'Select City';
            app.SelectdesigntemperatureCEditFieldLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.SelectdesigntemperatureCEditFieldLabel.Text = 'Select design temperature (C)';
            app.SelectdesigntemperatureCEditField = uieditfield(app.MAUA_CLTD69theditionUIFigure, 'numeric');
            app.SelectdesigntemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @SelectdesigntemperatureCEditFieldValueChanged, true);
            app.SelectdesigntemperatureCEditField.ValueDisplayFormat = '%11.3g';
            app.SelectdesigntemperatureCEditField.Value = 24;
            app.LatitudeLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.LatitudeLabel.Text = 'Latitude';
            app.MaxHumidityLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.MaxHumidityLabel.Text = 'Max Humidity';
            app.MonthLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.MonthLabel.Text = 'Month';
            app.MaxTemperatureLabel = uilabel(app.MAUA_CLTD69theditionUIFigure);
            app.MaxTemperatureLabel.Text = 'Max Temperature';
            app.ElectricalLoadButton = uibutton(app.MAUA_CLTD69theditionUIFigure, 'push');
            app.ElectricalLoadButton.ButtonPushedFcn = createCallbackFcn(app, @Equipment, true);
            app.ElectricalLoadButton.Text = 'Electrical Load';
            app.HumanLoadButton = uibutton(app.MAUA_CLTD69theditionUIFigure, 'push');
            app.HumanLoadButton.ButtonPushedFcn = createCallbackFcn(app, @HumanLoadButtonPushed, true);
            app.HumanLoadButton.Text = 'Human Load';
            app.VentilationButton = uibutton(app.MAUA_CLTD69theditionUIFigure, 'push');
            app.VentilationButton.ButtonPushedFcn = createCallbackFcn(app, @VentilationButtonPushed, true);
            app.VentilationButton.Text = 'Ventilation';
            app.InfiltrationLoadButton = uibutton(app.MAUA_CLTD69theditionUIFigure, 'push');
            app.InfiltrationLoadButton.ButtonPushedFcn = createCallbackFcn(app, @InfiltrationLoadButtonPushed, true);
            app.InfiltrationLoadButton.Text = 'Infiltration Load';
            app.WallloadButton = uibutton(app.MAUA_CLTD69theditionUIFigure, 'push');
            app.WallloadButton.ButtonPushedFcn = createCallbackFcn(app, @WallloadButtonPushed, true);
            app.WallloadButton.Text = 'Wall load';
            app.WindowLoadButton = uibutton(app.MAUA_CLTD69theditionUIFigure, 'push');
            app.WindowLoadButton.ButtonPushedFcn = createCallbackFcn(app, @WindowLoadButtonPushed, true);
            app.WindowLoadButton.Text = 'Window Load';
            app.EquipmentloadPanel = uipanel(app.MAUA_CLTD69theditionUIFigure);
            app.EquipmentloadPanel.Title = 'Equipment load';
            app.UITable = uitable(app.EquipmentloadPanel);
            app.UITable.ColumnName = {'Wattage'; 'Quantity'; '%age utilization'; 'Expected running time'; 'Expected Starting time'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CalculateButton = uibutton(app.EquipmentloadPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ElectricalLoadLabel = uilabel(app.EquipmentloadPanel);
            app.ElectricalLoadLabel.Text = 'Load in tonnes here';
            app.NoteLabel = uilabel(app.EquipmentloadPanel);
            app.NoteLabel.Text = 'Note: The first two loads describe lighting loads. Adjust Ballast factor manually.';
            app.SelectZoneDropDownLabel = uilabel(app.EquipmentloadPanel);
            app.SelectZoneDropDownLabel.Text = 'Select Zone';
            app.SelectZoneDropDown = uidropdown(app.EquipmentloadPanel);
            app.SelectZoneDropDown.Items = {'Select Zone', 'A', 'B', 'C', 'D'};
            app.SelectZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectZoneDropDownValueChanged, true);
            app.SelectZoneDropDown.Value = 'Select Zone';
            app.ExitButton = uibutton(app.EquipmentloadPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.HumanLoadPanel = uipanel(app.MAUA_CLTD69theditionUIFigure);
            app.HumanLoadPanel.Title = 'Human Load';
            app.UITable2 = uitable(app.HumanLoadPanel);
            app.UITable2.ColumnName = {'Quantity'; 'Time duration'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.CalculateButton_2 = uibutton(app.HumanLoadPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.HumanLabel = uilabel(app.HumanLoadPanel);
            app.HumanLabel.Text = 'Load in tons here';
            app.ExitButton_2 = uibutton(app.HumanLoadPanel, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_2Pushed, true);
            app.ExitButton_2.Text = 'Exit';
            app.VentilationRatePanel = uipanel(app.MAUA_CLTD69theditionUIFigure);
            app.VentilationRatePanel.Title = 'Ventilation Rate';
            app.EnterNumberofPeopleLabel = uilabel(app.VentilationRatePanel);
            app.EnterNumberofPeopleLabel.Text = 'Enter Number of People';
            app.peopleEditField = uieditfield(app.VentilationRatePanel, 'numeric');
            app.peopleEditField.Limits = [0 Inf];
            app.SelectTypeofroomsDropDownLabel = uilabel(app.VentilationRatePanel);
            app.SelectTypeofroomsDropDownLabel.Text = 'Select Type of rooms';
            app.TypeDropDown = uidropdown(app.VentilationRatePanel);
            app.TypeDropDown.Items = {'Select', 'Office', 'Meeting', 'Lobby'};
            app.TypeDropDown.Value = 'Select';
            app.Smoking = uicheckbox(app.VentilationRatePanel);
            app.Smoking.Text = 'Smoking is permitted';
            app.CalculateButton_3 = uibutton(app.VentilationRatePanel, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.ExitButton_3 = uibutton(app.VentilationRatePanel, 'push');
            app.ExitButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExitButton_3Pushed, true);
            app.ExitButton_3.Text = 'Exit';
            app.VentflowrateLabel = uilabel(app.VentilationRatePanel);
            app.VentflowrateLabel.Text = 'Vent flowrate';
            app.InfiltrationloadPanel = uipanel(app.MAUA_CLTD69theditionUIFigure);
            app.InfiltrationloadPanel.Title = 'Infiltration load';
            app.SelectconstructionDropDownLabel = uilabel(app.InfiltrationloadPanel);
            app.SelectconstructionDropDownLabel.Text = 'Select construction';
            app.SelectconstructionDropDown = uidropdown(app.InfiltrationloadPanel);
            app.SelectconstructionDropDown.Items = {'Tight', 'Average', 'Loose'};
            app.SelectconstructionDropDown.Value = 'Tight';
            app.EntervolumeEditFieldLabel = uilabel(app.InfiltrationloadPanel);
            app.EntervolumeEditFieldLabel.Text = 'Enter volume';
            app.EntervolumeEditField = uieditfield(app.InfiltrationloadPanel, 'numeric');
            app.EntervolumeEditField.Limits = [0 Inf];
            app.CalculateButton_4 = uibutton(app.InfiltrationloadPanel, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.LoadhereLabel = uilabel(app.InfiltrationloadPanel);
            app.LoadhereLabel.Text = 'Load here';
            app.CloseButton = uibutton(app.InfiltrationloadPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WallloadPanel = uipanel(app.MAUA_CLTD69theditionUIFigure);
            app.WallloadPanel.Title = 'Wall load';
            app.WalltypeEditFieldLabel = uilabel(app.WallloadPanel);
            app.WalltypeEditFieldLabel.Text = 'Wall type';
            app.WalltypeEditField = uieditfield(app.WallloadPanel, 'numeric');
            app.WalltypeEditField.Limits = [1 14];
            app.WalltypeEditField.Value = 1;
            app.WallareaEditFieldLabel = uilabel(app.WallloadPanel);
            app.WallareaEditFieldLabel.Text = 'Wall area';
            app.WallareaEditField = uieditfield(app.WallloadPanel, 'numeric');
            app.WallareaEditField.Limits = [0 Inf];
            app.WallresistanceEditFieldLabel = uilabel(app.WallloadPanel);
            app.WallresistanceEditFieldLabel.Text = 'Wall resistance';
            app.WallresistanceEditField = uieditfield(app.WallloadPanel, 'numeric');
            app.WallresistanceEditField.Limits = [0 Inf];
            app.WallfaceDropDownLabel = uilabel(app.WallloadPanel);
            app.WallfaceDropDownLabel.Text = 'Wall face';
            app.WallfaceDropDown = uidropdown(app.WallloadPanel);
            app.WallfaceDropDown.Items = {'Select option', 'hor', 'north', 'east', 'west', 'south'};
            app.WallfaceDropDown.Value = 'Select option';
            app.CalculateButton_5 = uibutton(app.WallloadPanel, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.LoadhereLabel_2 = uilabel(app.WallloadPanel);
            app.LoadhereLabel_2.Text = 'Load here';
            app.Add = uibutton(app.WallloadPanel, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add to total';
            app.closeButton = uibutton(app.WallloadPanel, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.TotalLoadLabel = uilabel(app.WallloadPanel);
            app.TotalLoadLabel.Text = 'Total Load';
            app.SolarLoadPanel = uipanel(app.MAUA_CLTD69theditionUIFigure);
            app.SolarLoadPanel.Title = 'Solar Load';
            app.WindowexposedareaEditFieldLabel = uilabel(app.SolarLoadPanel);
            app.WindowexposedareaEditFieldLabel.Text = 'Window exposed area';
            app.WindowexposedareaEditField = uieditfield(app.SolarLoadPanel, 'numeric');
            app.WindowexposedareaEditField.Limits = [0 Inf];
            app.WindowresistanceEditFieldLabel = uilabel(app.SolarLoadPanel);
            app.WindowresistanceEditFieldLabel.Text = 'Window resistance';
            app.WindowresistanceEditField = uieditfield(app.SolarLoadPanel, 'numeric');
            app.WindowresistanceEditField.Limits = [0 Inf];
            app.WindowfaceDropDownLabel = uilabel(app.SolarLoadPanel);
            app.WindowfaceDropDownLabel.Text = 'Window face';
            app.WindowfaceDropDown = uidropdown(app.SolarLoadPanel);
            app.WindowfaceDropDown.Items = {'Select option', 'hor', 'north', 'east', 'west', 'south'};
            app.WindowfaceDropDown.Value = 'Select option';
            app.CalculateButton_6 = uibutton(app.SolarLoadPanel, 'push');
            app.CalculateButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_6Pushed, true);
            app.CalculateButton_6.Text = 'Calculate';
            app.LoadhereLabel_3 = uilabel(app.SolarLoadPanel);
            app.LoadhereLabel_3.Text = 'Load here';
            app.Add_2 = uibutton(app.SolarLoadPanel, 'push');
            app.Add_2.ButtonPushedFcn = createCallbackFcn(app, @Add_2ButtonPushed, true);
            app.Add_2.Text = 'Add to total';
            app.closeButton_2 = uibutton(app.SolarLoadPanel, 'push');
            app.closeButton_2.ButtonPushedFcn = createCallbackFcn(app, @closeButton_2Pushed, true);
            app.closeButton_2.Text = 'close';
            app.TotalLoadLabel_2 = uilabel(app.SolarLoadPanel);
            app.TotalLoadLabel_2.Text = 'Total Load';
            app.ZonetypeDropDownLabel = uilabel(app.SolarLoadPanel);
            app.ZonetypeDropDownLabel.Text = 'Zone type';
            app.ZonetypeDropDown = uidropdown(app.SolarLoadPanel);
            app.ZonetypeDropDown.Items = {'Select', 'A', 'B', 'C', 'D'};
            app.ZonetypeDropDown.Value = 'Select';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HVAC_app_Minerva

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAUA_CLTD69theditionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAUA_CLTD69theditionUIFigure)
        end
    end
end